classdef spc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        NumuneBoyutuEditField       matlab.ui.control.NumericEditField
        NumuneBoyutuEditFieldLabel  matlab.ui.control.Label
        AnalizEtButton              matlab.ui.control.Button
        CSVYkleButton               matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RawData table % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CSVYkleButton
        function CSVYkleButtonPushed(app, event)
            [file, path] = uigetfile({'*.csv;*.xlsx', 'Veri Dosyaları'});
            if isequal(file, 0)
                app.TextArea.Value = 'Seçim iptal edildi.';
                return;
            end

            tamYol = fullfile(path, file);
            app.RawData = readtable(tamYol);
            app.TextArea.Value = sprintf('Başarılı: %s yüklendi.', file);
        end

        % Button pushed function: AnalizEtButton
        function AnalizEtButtonPushed(app, event)
            if isempty(app.RawData)
                app.TextArea.Value = 'Önce CSV Yükle butonundan dosya seçin!';
                return;
            end

            tablo = app.RawData;
            partiListesi = unique(tablo.Parti_No);
            numBatches = length(partiListesi);

            x_bar = zeros(numBatches, 1);
            r_val = zeros(numBatches, 1);

            for i = 1:numBatches
                pNo = partiListesi(i);
                olcumler = tablo.Gramaj(tablo.Parti_No == pNo);
                x_bar(i) = mean(olcumler);
                r_val(i) = max(olcumler) - min(olcumler);
            end

            % ASTM n=5 Katsayıları
            A2 = 0.577; D3 = 0; D4 = 2.114;

            X_double_bar = mean(x_bar);
            R_bar = mean(r_val);

            ucl_x = X_double_bar + A2 * R_bar;
            lcl_x = X_double_bar - A2 * R_bar;
            ucl_r = D4 * R_bar;
            lcl_r = D3 * R_bar;

            % Üst Grafik (X-bar)
            cla(app.UIAxes);
            plot(app.UIAxes, partiListesi, x_bar, '-o', 'Color', [0.12 0.47 0.71], 'LineWidth', 1.5);
            yline(app.UIAxes, X_double_bar, 'g-', sprintf('CL: %.2f', X_double_bar));
            yline(app.UIAxes, ucl_x, 'r--', sprintf('UCL: %.2f', ucl_x));
            yline(app.UIAxes, lcl_x, 'r--', sprintf('LCL: %.2f', lcl_x));
            title(app.UIAxes, 'X-bar Kontrol Grafiği');
            grid(app.UIAxes, 'on');

            % Alt Grafik (R)
            cla(app.UIAxes2);
            plot(app.UIAxes2, partiListesi, r_val, '-s', 'Color', [1 0.5 0], 'LineWidth', 1.5);
            yline(app.UIAxes2, R_bar, 'g-', sprintf('CL: %.2f', R_bar));
            yline(app.UIAxes2, ucl_r, 'r--', sprintf('UCL: %.2f', ucl_r));
            yline(app.UIAxes2, lcl_r, 'r--', sprintf('LCL: %.2f', lcl_r));
            title(app.UIAxes2, 'R Kontrol Grafiği');
            grid(app.UIAxes2, 'on');

            % Rapor Yazdır
            app.TextArea.Value = sprintf('Analiz Tamamlandı! Toplam Parti: %d | Ortalama Gramaj: %.2f g', numBatches, X_double_bar);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CSVYkleButton = uibutton(app.UIFigure, 'push');
            app.CSVYkleButton.ButtonPushedFcn = createCallbackFcn(app, @CSVYkleButtonPushed, true);
            app.CSVYkleButton.Text = 'CSV Yükle';
            app.AnalizEtButton = uibutton(app.UIFigure, 'push');
            app.AnalizEtButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizEtButtonPushed, true);
            app.AnalizEtButton.Text = 'Analiz Et';
            app.NumuneBoyutuEditFieldLabel = uilabel(app.UIFigure);
            app.NumuneBoyutuEditFieldLabel.Text = 'Numune Boyutu';
            app.NumuneBoyutuEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumuneBoyutuEditField.Value = 5;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end